classdef Editor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        ParamEditField             matlab.ui.control.EditField
        ParamEditFieldLabel        matlab.ui.control.Label
        ResetViewButton            matlab.ui.control.Button
        ShapeNameEditField         matlab.ui.control.EditField
        ShapeNameEditFieldLabel    matlab.ui.control.Label
        RenameParamEditField       matlab.ui.control.EditField
        RenameParamEditFieldLabel  matlab.ui.control.Label
        MorphSliderLabel_2         matlab.ui.control.Label
        ModeSwitch                 matlab.ui.control.Switch
        TestView                   matlab.ui.control.CheckBox
        ListBox                    matlab.ui.control.ListBox
        GroupEditField             matlab.ui.control.EditField
        GroupEditFieldLabel        matlab.ui.control.Label
        ObjectListBox              matlab.ui.control.ListBox
        ResolveMeshButton          matlab.ui.control.Button
        Alpha10EditFieldLabel      matlab.ui.control.Label
        Alpha10EditField           matlab.ui.control.NumericEditField
        ImportButton               matlab.ui.control.Button
        MorphSlider                matlab.ui.control.Slider
        MorphSliderLabel           matlab.ui.control.Label
        LayerSpinner               matlab.ui.control.Spinner
        LayerSpinnerLabel          matlab.ui.control.Label
        ParamListBox               matlab.ui.control.ListBox
        SetShapeButton             matlab.ui.control.Button
        ExportButton               matlab.ui.control.Button
        AddObjectButton            matlab.ui.control.Button
        AreaROI                    matlab.ui.control.UIAxes
        AreaImg                    matlab.ui.control.UIAxes
        Overview                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        objs % Description
        current % Description
        morph % Description
        mode % Description
        current_ROI % Description
        params % Description
    end
    
    methods (Access = private)
        
        function dispMeshes(app)
            %disp("dispMeshes");
            if app.current ~= 0
                cla(app.AreaImg);
                hold(app.AreaImg,'on')
                for i = 1:length(app.objs)
                    if i == app.current || app.TestView.Value
                        transparency = 1;
                    else
                        transparency = 0.5;
                    end
                    image(app.AreaImg,app.objs(i).img,"AlphaData",app.objs(i).mask.*transparency);                    
                end
                hold(app.AreaImg,'off')
                points = app.objs(app.current).mesh_points;
                meshes = app.objs(app.current).meshes;        
                dispEdges(app,points,meshes);
            end
        end
        
        function resolveMeshes(app)
            %disp("resolveMesh");
            if app.current ~= 0
                ROI = app.current_ROI;
                points_num = length(ROI);
                points = zeros(points_num,2);
                for i = 1:points_num
                    points(i,1) = ROI{i}.Position(1);
                    points(i,2) = ROI{i}.Position(2);
                end
                app.objs(app.current).mesh_points = points;
                meshes = point2mesh(app,points,app.Alpha10EditField.Value*10);
                app.objs(app.current).meshes = meshes;
                dispMeshes(app);
            end
        end
        
        function setShape(app)
            %disp("setShape");
            ROI = app.current_ROI;
            points_num = length(ROI);
            points = zeros(points_num,2);
            for i = 1:points_num
                points(i,1) = ROI{i}.Position(1);
                points(i,2) = ROI{i}.Position(2);
            end
            app.objs(app.current).shape_points = points;
            dispShape(app);
        end
        
        function dispEdges(app,points,meshes)
            if app.TestView.Value ~= 1
                [mesh_num,~] = size(meshes);
                hold(app.AreaImg,"on");
                for i = 1:mesh_num
                    idxs = meshes(i,:);
                    plot(app.AreaImg,[points(idxs,1); points(idxs(1),1)],[points(idxs,2); points(idxs(1),2)],color=[0 1 0])
                end
                hold(app.AreaImg,"off");
            end
        end
        
        function MORPH = dispShape(app)
            %disp("dispShape");
            
            current_morph_points = [];
            does_current_has_shape = false;
            
            cla(app.AreaImg);
            hold(app.AreaImg,'on')
            for i = 1:length(app.objs)
                if i == app.current || app.TestView.Value
                    transparency = 1;
                else
                    transparency = 0.5;
                end
                
                if ~isempty(app.objs(i).mesh_points) &&...
                        size(app.objs(i).mesh_points,1)...
                        == size(app.objs(i).shape_points,1)
                    [img,mask,morph_points] = obj2img(app,app.objs(i)); 
                    
                    image(app.AreaImg,img,"AlphaData",mask.*transparency);
                    if i == app.current
                        meshes = app.objs(app.current).meshes;
                        current_morph_points = morph_points;
                        does_current_has_shape = true;
                    end
                else
                    image(app.AreaImg,app.objs(i).img,"AlphaData",app.objs(i).mask.*transparency);                    
                end
            end
            hold(app.AreaImg,'off')
            if does_current_has_shape
                dispEdges(app,current_morph_points,meshes); 
            end
            MORPH = current_morph_points;
        end
        
        function rearrangeROI(app,points,noCall)
            %disp(points);
            %disp("rearrangeROI");
            if ~exist('noCall','var'), noCall = false; end
            
            cla(app.AreaROI);
            [points_num, ~] = size(points);
            
            if ~noCall
                app.current_ROI = {};
            end
            
            for i = 1:points_num
                app.current_ROI{i} = drawpoint(app.AreaROI,Position=[points(i,1) points(i,2)],color=[0 1 0],Visible=~app.TestView.Value);
                addlistener(app.current_ROI{i},'MovingROI',@(src,event) ROIcallback(app,event));
            end
            
            if ~noCall
                ROIcallback(app);
            end
        end
        
        function setupArea(app)
            [W,H,~]=size(app.objs(length(app.objs)).img);
            image(app.AreaImg,app.objs(length(app.objs)).img);
            
            
            pbaspect(app.AreaImg,[1 1 1]);
            pbaspect(app.AreaROI,[1 1 1]);
                
            app.AreaImg.XLim = [1,W];
            app.AreaImg.YLim = [1,W];
            
            app.AreaROI.YDir = 'reverse';    
            app.AreaROI.XLim = [1,W];
            app.AreaROI.YLim = [1,H];
            
            
            app.ModeSwitch.Value = 'Mesh';
            app.MorphSlider.Value = 1;
        
            app.current_ROI = {};
            
            cla(app.AreaROI);
        end
        
        function ROIcallback(app, ~)
            %disp("ROIcallback");
            if app.ModeSwitch.Value == "Mesh"
                resolveMeshes(app);
            else
                app.MorphSlider.Value = 1;
                
                setShape(app);
            end
        end
        
        
        function reRender(app)    
            if app.ModeSwitch.Value == "Mesh"
                points = app.objs(app.current).mesh_points;
            elseif app.ModeSwitch.Value == "Shape"
                points = app.objs(app.current).shape_points;
            end
            rearrangeROI(app,points);
            reSetParamSlider(app);
        end
        
        function reScanParams(app)
            app.ParamListBox.Items = {};
            for i = 1:length(app.objs)
               if sum(strcmp(app.objs(i).param,app.ParamListBox.Items)) == 0
                    app.ParamListBox.Items{length(app.ParamListBox.Items)+1} = app.objs(i).param;               
               end
            end
        end
        
        function reSetParamSlider(app)
            for i = 1:length(app.objs)
                if strcmp(app.ParamListBox.Value,app.objs(i).param)
                    app.MorphSlider.Value = app.objs(i).param_value;
                    break
                end
            end
        end
        
        function [layer_img,layer_mask,morph_points] = obj2img(app,obj)
            blender = vision.AlphaBlender('Operation','Binary mask','MaskSource','Input port');
            
            img = im2single(obj.img);
            mask = im2single(obj.mask);
            
            mesh_points = obj.mesh_points;
            meshes = obj.meshes;
            shape_points = obj.shape_points;
            morph_points = shape_points.*obj.param_value+mesh_points.*(1-obj.param_value);
        
            [W,H,Ch] = size(img);
            layer_img = single(zeros([W,H,Ch]));
            layer_mask = single(zeros([W,H]));
        
            [mesh_num,~] = size(meshes);
        
            for i = 1:mesh_num
                idxs = meshes(i,:);
                mesh_verts = mesh_points(idxs,:);
                morph_verts = morph_points(idxs,:);
                
                xRange = round([min([mesh_verts(:,1);morph_verts(:,1)]),...
                    max([mesh_verts(:,1);morph_verts(:,1)])]);
                yRange = round([min([mesh_verts(:,2);morph_verts(:,2)]),...
                    max([mesh_verts(:,2);morph_verts(:,2)])]);
                
                mesh_W = xRange(2)-xRange(1)+1;
                mesh_H = yRange(2)-yRange(1)+1;
                
                origin_corr = [xRange(1)-1,yRange(1)-1];
                
                mesh_verts = mesh_verts - [origin_corr;origin_corr;origin_corr];
                morph_verts = morph_verts - [origin_corr;origin_corr;origin_corr];
                
                mesh_mask_range = mask(yRange(1):yRange(2),xRange(1):xRange(2),:).* ...
                    poly2mask(mesh_verts(:,1),mesh_verts(:,2), mesh_H, mesh_W);
        
                T = transCoeff(app,mesh_verts,morph_verts);
                
                tform = affine2d(T);
                outView = affineOutputView([mesh_H,mesh_W],tform,'BoundsStyle','sameAsInput');
        
                mesh_img = single(zeros([W,H,3]));
                mesh_mask = single(zeros([W,H,1]));
                
                mesh_img(yRange(1):yRange(2),xRange(1):xRange(2),:) = ...
                    imwarp(img(yRange(1):yRange(2),xRange(1):xRange(2),:),tform,'OutputView',outView);
                mesh_mask(yRange(1):yRange(2),xRange(1):xRange(2)) = ...
                    imwarp(mesh_mask_range,tform,'OutputView',outView);
        
                layer_img = blender(layer_img,mesh_img,mesh_mask);
                layer_mask = layer_mask+mesh_mask;
                
            end
        end
        
        function T = transCoeff(~,P,Q)
            %三点Pを三点Qに写す行列を返す
            %P,Qは[[x y]; [x y]; [x y]]の形式
            A = [P [1; 1; 1]];
            coeffMat = [[A; zeros(3)] [zeros(3); A]];
            B = reshape(Q, [6,1]);
            coeffVec = coeffMat\B;
            T = [reshape(coeffVec, [3,2]).'; [0 0 1]].';        
        end
        
        function mesh = point2mesh(~,points,alpha)
            shp = alphaShape(points(:,1),points(:,2));
            shp.Alpha = alpha;
            mesh = alphaTriangulation(shp);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.current = 0;
            app.ModeSwitch.Value = "Mesh";
        end

        % Button pushed function: AddObjectButton
        function AddObjButtonPushed(app, event)
            [file,path] = uigetfile("*.png");
            try
                    app.current = length(app.objs)+1;
                    [img,~,mask]=imread(append(path,file));
                    app.objs(app.current).name = erase(file,'.png');
                    app.objs(app.current).param = erase(file,'.png');
                    app.objs(app.current).param_value = 1;
                    
                    app.objs(app.current).img = img;
                    app.objs(app.current).mask = mask;
                    app.objs(app.current).mesh_points = [];
                    app.objs(app.current).meshes = [];
                    app.objs(app.current).shape_points = [];
                    app.ObjectListBox.Items{length(app.ObjectListBox.Items)+1} = app.objs(app.current).name;
                    app.ParamListBox.Items{length(app.ParamListBox.Items)+1} = app.objs(app.current).param;
                    
                    app.ObjectListBox.Value= app.objs(app.current).name;
                    
                    app.ParamListBox.Value= app.objs(app.current).param;
                    app.RenameParamEditField.Value = app.objs(app.current).param;
                    
                    setupArea(app);
                    dispMeshes(app);
                    app.LayerSpinner.Value = app.current;
            catch
                disp('error')
            end
        end

        % Button pushed function: ResolveMeshButton
        function ResolveMeshesButtonPushed(app, event)
            if app.current~=0
                if app.ModeSwitch.Value == "Shape"
                    app.ModeSwitch.Value = "Mesh"; 
                    points = app.objs(app.current).mesh_points;
                    rearrangeROI(app,points); 
                else
                    resolveMeshes(app);
                end
            end        
        end

        % Value changed function: Alpha10EditField
        function Alpha10EditFieldValueChanged(app, event)
            if app.ModeSwitch.Value == "Mesh"
                resolveMeshes(app)
            end
        end

        % Button down function: AreaROI
        function AreaROIButtonDown(app, event)
            if app.TestView.Value
                app.TestView.Value = false;
                reRender(app);
            else
                if app.current ~= 0 && app.ModeSwitch.Value == "Mesh"
                    roi = drawpoint(app.AreaROI,color=[0 1 0]);
                    app.current_ROI{1+length(app.current_ROI)} = roi; 
                    resolveMeshes(app);
                    rearrangeROI(app,app.objs(app.current).mesh_points);
                end
            end    
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            [file,path] = uiputfile('*.mat');
            if file ~= 0
                OBJS = app.objs;
                save(append(path,file),"OBJS",'-mat');
            end
        end

        % Button pushed function: SetShapeButton
        function SetShapeButtonPushed(app, event)
            if app.current ~= 0
                app.ModeSwitch.Value = "Shape";
                setShape(app);
            end
        end

        % Value changing function: MorphSlider
        function MorphSliderValueChanging(app, event)
            value = event.Value;
            if app.current~=0
                for i = 1:length(app.objs)
                    if strcmp(app.ParamListBox.Value,app.objs(i).param)
                        app.objs(i).param_value = value;
                    end
                end
                app.ModeSwitch.Value="Shape";
                morph_points = dispShape(app);
                rearrangeROI(app,morph_points,true);
            end
        end

        % Value changed function: ModeSwitch
        function ModeSwitchValueChanged(app, event)
            if app.current~=0
                if app.ModeSwitch.Value == "Shape"
                    resolveMeshes(app);
                    m_points = app.objs(app.current).mesh_points;
                    s_points = app.objs(app.current).shape_points;
                    
                    if size(m_points,1) ~= size(s_points,1)
                        app.objs(app.current).shape_points = m_points;
                        rearrangeROI(app,m_points);
                    else
                        rearrangeROI(app,s_points);
                    end
                else
                    points = app.objs(app.current).mesh_points;
                    rearrangeROI(app,points);
                end
            end
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            [file,path] = uigetfile();
            if file ~= 0
                load(append(path,file),"-mat","OBJS");
                app.objs = OBJS;
                app.current = length(app.objs);
                for i = 1:app.current
                    app.ObjectListBox.Items{i} = app.objs(i).name;
                end
                reScanParams(app);
                app.ObjectListBox.Value= app.objs(app.current).name;            
                
                setupArea(app);
                app.ModeSwitch.Value = "Mesh";
                rearrangeROI(app,app.objs(app.current).mesh_points);
            end
        end

        % Value changed function: ObjectListBox
        function ObjectListBoxValueChanged(app, event)
            [~,idx] = ismember(app.ObjectListBox.Items,app.ObjectListBox.Value);
            app.current = find(idx);
            app.LayerSpinner.Value = app.current;
            app.ParamListBox.Value = app.objs(app.current).param;
            reSetParamSlider(app)
            
            if isempty(app.objs(app.current).mesh_points)
                app.ModeSwitch.Value = "Mesh";
                rearrangeROI(app,[]);
                dispMeshes(app);
            else
                reRender(app);
            end
        end

        % Value changed function: LayerSpinner
        function LayerSpinnerValueChanged(app, event)
            value = 2*app.current-app.LayerSpinner.Value;
            if 0<value && value<=length(app.objs)
                temp = app.objs(value);
                temp_name = app.ObjectListBox.Items{value};
                
                app.objs(value) = app.objs(app.current);
                app.ObjectListBox.Items{value} = app.ObjectListBox.Items{app.current};
                
                app.objs(app.current) = temp;
                app.ObjectListBox.Items{app.current} = temp_name;
                
                app.current = value;
                
                reRender(app);
                
                app.LayerSpinner.Value=value;
            else
                app.LayerSpinner.Value = app.current;
            end
        end

        % Value changed function: TestView
        function TestViewValueChanged(app, event)
            reRender(app);
        end

        % Value changed function: RenameParamEditField
        function RenameParamEditFieldValueChanged(app, event)
            app.objs(app.current).param = app.RenameParamEditField.Value;
            reScanParams(app);
            app.ParamListBox.Value = app.objs(app.current).param;
        end

        % Value changed function: ParamListBox
        function ParamListBoxValueChanged(app, event)
            for i = 1:length(app.objs)
                if strcmp(app.objs(i).param,app.ParamListBox.Value)
                    app.current = i;
                    app.ObjectListBox.Value = app.ObjectListBox.Items{i};
                    reRender(app);
                    break
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Overview = uiaxes(app.GridLayout);
            title(app.Overview, 'Overview')
            xlabel(app.Overview, 'X')
            ylabel(app.Overview, 'Y')
            zlabel(app.Overview, 'Z')
            app.AreaImg = uiaxes(app.GridLayout);
            xlabel(app.AreaImg, 'X')
            ylabel(app.AreaImg, 'Y')
            zlabel(app.AreaImg, 'Z')
            app.AreaROI = uiaxes(app.GridLayout);
            xlabel(app.AreaROI, 'X')
            ylabel(app.AreaROI, 'Y')
            zlabel(app.AreaROI, 'Z')
            app.AreaROI.ButtonDownFcn = createCallbackFcn(app, @AreaROIButtonDown, true);
            app.AddObjectButton = uibutton(app.GridLayout, 'push');
            app.AddObjectButton.ButtonPushedFcn = createCallbackFcn(app, @AddObjButtonPushed, true);
            app.AddObjectButton.Text = 'Add Object';
            app.ExportButton = uibutton(app.GridLayout, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.SetShapeButton = uibutton(app.GridLayout, 'push');
            app.SetShapeButton.ButtonPushedFcn = createCallbackFcn(app, @SetShapeButtonPushed, true);
            app.SetShapeButton.Text = 'Set Shape';
            app.ParamListBox = uilistbox(app.GridLayout);
            app.ParamListBox.Items = {};
            app.ParamListBox.ValueChangedFcn = createCallbackFcn(app, @ParamListBoxValueChanged, true);
            app.ParamListBox.Value = {};
            app.LayerSpinnerLabel = uilabel(app.GridLayout);
            app.LayerSpinnerLabel.Text = 'Layer';
            app.LayerSpinner = uispinner(app.GridLayout);
            app.LayerSpinner.ValueChangedFcn = createCallbackFcn(app, @LayerSpinnerValueChanged, true);
            app.MorphSliderLabel = uilabel(app.GridLayout);
            app.MorphSliderLabel.Text = 'Morph';
            app.MorphSlider = uislider(app.GridLayout);
            app.MorphSlider.Limits = [0 1];
            app.MorphSlider.ValueChangingFcn = createCallbackFcn(app, @MorphSliderValueChanging, true);
            app.MorphSlider.Value = 1;
            app.ImportButton = uibutton(app.GridLayout, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.Alpha10EditField = uieditfield(app.GridLayout, 'numeric');
            app.Alpha10EditField.ValueChangedFcn = createCallbackFcn(app, @Alpha10EditFieldValueChanged, true);
            app.Alpha10EditField.Value = Inf;
            app.Alpha10EditFieldLabel = uilabel(app.GridLayout);
            app.Alpha10EditFieldLabel.Text = 'Alpha (×10)';
            app.ResolveMeshButton = uibutton(app.GridLayout, 'push');
            app.ResolveMeshButton.ButtonPushedFcn = createCallbackFcn(app, @ResolveMeshesButtonPushed, true);
            app.ResolveMeshButton.Text = 'Resolve Mesh';
            app.ObjectListBox = uilistbox(app.GridLayout);
            app.ObjectListBox.Items = {};
            app.ObjectListBox.ValueChangedFcn = createCallbackFcn(app, @ObjectListBoxValueChanged, true);
            app.ObjectListBox.Value = {};
            app.GroupEditFieldLabel = uilabel(app.GridLayout);
            app.GroupEditFieldLabel.Text = 'Group';
            app.GroupEditField = uieditfield(app.GridLayout, 'text');
            app.ListBox = uilistbox(app.GridLayout);
            app.ListBox.Items = {};
            app.ListBox.Value = {};
            app.TestView = uicheckbox(app.GridLayout);
            app.TestView.ValueChangedFcn = createCallbackFcn(app, @TestViewValueChanged, true);
            app.TestView.Text = 'Test Vew';
            app.ModeSwitch = uiswitch(app.GridLayout, 'slider');
            app.ModeSwitch.Items = {'Mesh', 'Shape'};
            app.ModeSwitch.ValueChangedFcn = createCallbackFcn(app, @ModeSwitchValueChanged, true);
            app.ModeSwitch.Value = 'Mesh';
            app.MorphSliderLabel_2 = uilabel(app.GridLayout);
            app.MorphSliderLabel_2.Text = 'Mode';
            app.RenameParamEditFieldLabel = uilabel(app.GridLayout);
            app.RenameParamEditFieldLabel.Text = 'Rename Param';
            app.RenameParamEditField = uieditfield(app.GridLayout, 'text');
            app.RenameParamEditField.ValueChangedFcn = createCallbackFcn(app, @RenameParamEditFieldValueChanged, true);
            app.ShapeNameEditFieldLabel = uilabel(app.GridLayout);
            app.ShapeNameEditFieldLabel.Text = 'Shape Name';
            app.ShapeNameEditField = uieditfield(app.GridLayout, 'text');
            app.ResetViewButton = uibutton(app.GridLayout, 'push');
            app.ResetViewButton.Text = 'ResetView';
            app.ParamEditFieldLabel = uilabel(app.GridLayout);
            app.ParamEditFieldLabel.Text = 'Param';
            app.ParamEditField = uieditfield(app.GridLayout, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Editor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end