classdef MeshEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Toolbar         matlab.ui.container.Toolbar
        PushTool        matlab.ui.container.toolbar.PushTool
        GridLayout      matlab.ui.container.GridLayout
        OverviewButton  matlab.ui.control.Button
        Tree            matlab.ui.container.Tree
        MeshEditorNode  matlab.ui.container.TreeNode
        ExprotButton    matlab.ui.control.Button
        ImportButton    matlab.ui.control.Button
        MainEditor      matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            [file,path] = uigetfile('*.png');
            if file~=0
                [img,~,~] = imread(append(path,file));
                
                img = im2single(img);
            
                pbaspect(app.MainEditor,[1 1 1]);
                app.MainEditor.XLim = [-inf,inf];
                app.MainEditor.YLim = [-inf,inf];
                I = imshow(img,Parent = app.MainEditor);
            end
        end

        % Button pushed function: OverviewButton
        function OverviewButtonPushed(app, event)
                app.MainEditor.XLim = [-inf,inf];
                app.MainEditor.YLim = [-inf,inf];
        end

        % Callback function
        function UIAxesButtonDown(app, event)
            roi = drawpoint(app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.MainEditor = uiaxes(app.GridLayout);
            xlabel(app.MainEditor, 'X')
            ylabel(app.MainEditor, 'Y')
            zlabel(app.MainEditor, 'Z')
            app.MainEditor.XLim = [0 1];
            app.ImportButton = uibutton(app.GridLayout, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.ExprotButton = uibutton(app.GridLayout, 'push');
            app.ExprotButton.Text = 'Exprot';
            app.Tree = uitree(app.GridLayout);
            app.MeshEditorNode = uitreenode(app.Tree);
            app.MeshEditorNode.Text = 'Mesh Editor';
            app.OverviewButton = uibutton(app.GridLayout, 'push');
            app.OverviewButton.ButtonPushedFcn = createCallbackFcn(app, @OverviewButtonPushed, true);
            app.OverviewButton.Text = 'Overview';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MeshEditor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end