classdef mouthtest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        MouthSlider       matlab.ui.control.Slider
        MouthSliderLabel  matlab.ui.control.Label
        UIAxes            matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: MouthSlider
        function MouthSliderValueChanged(app, event)
            value = app.MouthSlider.Value;
            
        end

        % Value changing function: MouthSlider
        function MouthSliderValueChanging(app, event)
            param = event.Value;
            %画像読み込み
            [bk]  = im2single(imread("imgs\bk.png"));
            [mouth,~,mask] = imread("imgs\mouth.png");
            mouth = im2single(mouth);
            
            mesh = [[209 208];[274 208];[246 263]];
            open = mesh;
            close = [[209 226];[274 226];[246 226]];
            
            morph = open.*param+close.*(1-param);
            
            T = transCoeff(mesh,morph);
            tform = affine2d(T);
            outView = affineOutputView(size(mouth),tform,'BoundsStyle','sameAsInput');
            mouth = imwarp(mouth,tform,'OutputView',outView);
            mask = imwarp(mask,tform,'OutputView',outView);
            
            blender = vision.AlphaBlender('Operation','Binary mask','MaskSource','Input port');
            face = blender(bk,mouth,mask);
            image(app.UIAxes,face);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.MouthSliderLabel = uilabel(app.UIFigure);
            app.MouthSliderLabel.Text = 'Mouth';
            app.MouthSlider = uislider(app.UIFigure);
            app.MouthSlider.Limits = [0 1];
            app.MouthSlider.ValueChangedFcn = createCallbackFcn(app, @MouthSliderValueChanged, true);
            app.MouthSlider.ValueChangingFcn = createCallbackFcn(app, @MouthSliderValueChanging, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mouthtest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end